classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TextoButton          matlab.ui.control.Button
        ImagenButton         matlab.ui.control.Button
        ColoresListBox       matlab.ui.control.ListBox
        ColoresListBoxLabel  matlab.ui.control.Label
        ImagePreview         matlab.ui.control.Image
        UIAxes               matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function img = capture(app)
            cam = webcam('Integrated');
            img = snapshot(cam); 
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImagenButton
        function powerUp(app, event)
            clc; 
            while true
                img = capture(app); 
                 

                [image, modified_image, gray_image, bw_image] = preprocess_image(img, 512);
                app.ImagePreview.ImageSource = modified_image; 
                
                S = qtdecomp(gray_image,0.2);

                sizes = [512 256 128 64];
                boxes = [];
                coords = [];
                for dim = sizes
                    [values, rows, columns] = qtgetblk(gray_image,S,dim);
                    dims = repmat(dim,size(rows));
                    boxes = [boxes; columns rows dims dims];
                    coords = [coords; rows rows+dim-1 columns columns+dim-1];
                end
                
                predictions = [];
                %app.ImageBoxes.ImageSource = modified_image;
                imshow(modified_image, 'Parent', app.UIAxes);
                if length(boxes) > 4
                    hold(app.UIAxes,'on'); 
                    for index = 1: length(boxes)
                        sub_img = get_sub_image(modified_image,coords(index,:));
    
                        prediction = predict_matrix_image(sub_img,'ann');
                    
                        prediction = convertCharsToStrings(prediction);
                    
                        color = 'k';
                        if (contains(prediction, 'black') || contains(prediction, 'grey') ...
                                || contains(prediction, 'blue'))
                            color = 'w';
                        end
                    
                        text(app.UIAxes,'Position',[boxes(index,1) boxes(index,2)+10],'String',prediction, 'Color',color);
                        rectangle(app.UIAxes,'position', boxes(index,:), 'EdgeColor','r');
                    
                        predictions = [predictions; index prediction];
                    end
                    hold(app.UIAxes,'off');
                    title(app.UIAxes,'BoundingBox')
                    predictions_founded = unique(predictions(:,2));
                    app.ColoresListBox.Items = predictions_founded; 
                end
                toc

                pause(2);
            end
            
            
        end

        % Button pushed function: TextoButton
        function showVideo(app, event)
            clc; 
            while true
                img = capture(app); 
                 

                [image, modified_image, gray_image, bw_image] = preprocess_image(img, 512);
                app.ImagePreview.ImageSource = modified_image; 
                
                S = qtdecomp(gray_image,0.2);

                sizes = [512 256 128 64];
                boxes = [];
                coords = [];
                for dim = sizes
                    [values, rows, columns] = qtgetblk(gray_image,S,dim);
                    dims = repmat(dim,size(rows));
                    boxes = [boxes; columns rows dims dims];
                    coords = [coords; rows rows+dim-1 columns columns+dim-1];
                end
                
                predictions = [];
                %app.ImageBoxes.ImageSource = modified_image;
                
                if length(boxes) > 4
                    
                    for index = 1: length(boxes)
                        sub_img = get_sub_image(modified_image,coords(index,:));
    
                        prediction = predict_matrix_image(sub_img,'knn');
                    
                        prediction = convertCharsToStrings(prediction);
                    
                        predictions = [predictions; index prediction];
                    end      
                  
                    app.ColoresListBox.Items = unique(predictions(:,2)); 
                end
                toc

                pause(2);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ImagePreview = uiimage(app.UIFigure);
            app.ImagePreview.Tag = 'previewImgs';
            app.ColoresListBoxLabel = uilabel(app.UIFigure);
            app.ColoresListBoxLabel.Text = 'Colores';
            app.ColoresListBox = uilistbox(app.UIFigure);
            app.ImagenButton = uibutton(app.UIFigure, 'push');
            app.ImagenButton.ButtonPushedFcn = createCallbackFcn(app, @powerUp, true);
            app.ImagenButton.Tag = 'btnPowerUp';
            app.ImagenButton.Text = 'Imagen';
            app.TextoButton = uibutton(app.UIFigure, 'push');
            app.TextoButton.ButtonPushedFcn = createCallbackFcn(app, @showVideo, true);
            app.TextoButton.Text = 'Texto';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end